classdef spike_sorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DataPathEditFieldLabel  matlab.ui.control.Label
        DataPathEditField       matlab.ui.control.EditField
        RunButton               matlab.ui.control.Button
        SelectClusterButton     matlab.ui.control.Button
        AddnewClusterButton     matlab.ui.control.Button
        FunctionListBoxLabel    matlab.ui.control.Label
        FunctionListBox         matlab.ui.control.ListBox
        RunFunctionButton       matlab.ui.control.Button
        DeleteClusterxButton    matlab.ui.control.Button
        ClusterxListBoxLabel    matlab.ui.control.Label
        ClusterxListBox         matlab.ui.control.ListBox
        AddtoClusterxButton     matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)

        %把所有的spikes都画出来
        function waveformsPlotting(app,option)
            global data;
            global c;
            global colors;
            global n;
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');
            t = (0.1:0.1:spikes_length/10)';
            
            switch option
                case 'Show all Spikes'
                    cla(app.UIAxes);
                    c = plot(app.UIAxes,t,data.waveforms','color','#0072BD');
                    k=length(c);
                    title(app.UIAxes,{'waveforms number:' k});
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'Show x'
                    cla(app.UIAxes);
                    
                    number = size(app.ClusterxListBox.Value,2);
                    d = 0;
                    for i = 1:number
                        name = split(app.ClusterxListBox.Value(i),' ');
                        m = str2double(name{2});
                        index = data.idx == m;
                    
                        if size(colors,1)<m
                            plot(app.UIAxes,t,data.waveforms(index,:)','color','#0072BD');
                            hold(app.UIAxes,"on");
                        else
                            plot(app.UIAxes,t,data.waveforms(index,:)','color',colors(m,:));
                            hold(app.UIAxes,"on");
                        end
                        d = d + size(find(data.idx==m),1);
                    end
                    
                    k = length(d);
                    title(app.UIAxes,{'waveforms number:' k});
                    %spikes_max = max(data.waveforms(index,:),[],'all');
                    %spikes_min = min(data.waveforms(index,:),[],'all');
                    %axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'Show All Clusters'
                    cla(app.UIAxes);
                    colors = distinguishable_colors(n,'w');
                    for i = 1:n
                        plot(app.UIAxes,t,data.waveforms(data.idx == i,:)','color',colors(i,:));
                        hold(app.UIAxes,"on");
                    end
                    index = data.idx ~= 0;
                    number = length(index);
                    title(app.UIAxes,{'waveforms number:' number}); 
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                case 'View x in data'
                    
                case 'View all in data'
                    
                case 'x''s frequency'
                    
                case 'all''s frequency'
                    
                case 'showUnsortedSpikes'
                    cla(app.UIAxes);
                    rest = find(~data.idx);
                    c(rest) = plot(app.UIAxes,t,data.waveforms(rest,:)','Color','#0072BD');
                    spikes_length = size(data.waveforms,2);
                    spikes_max = max(data.waveforms(rest,:),[],'all');
                    spikes_min = min(data.waveforms(rest,:),[],'all');  
                    k=length(rest);
                    title(app.UIAxes,{'waveforms number:' k});
                    axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]); 
            end

        end

        %{
        %spikes重合的点不再重复画
        function waveformsPlotting(app)
            global data;
            spikes_length = size(data.waveforms,2);
            spikes_max = max(data.waveforms,[],'all');
            spikes_min = min(data.waveforms,[],'all');            
            for i = 1:spikes_length
                a = unique(data.waveforms(:,i));
                scatter(app.UIAxes,i*ones(size(a,1),1),a,".",'MarkerEdgeColor','#0072BD');
                hold on;
                axis([0 spikes_length spikes_min spikes_max]);
            end
            
        end
        
        %}
    end
    
    methods (Access = private)
        
        function addClusters(app)
            global n;
            global data;
            global rectangle;
            global c;
            spikes_length = size(data.waveforms,2);
            
            m = size(data.waveforms,1);
            t1=round(rectangle.Position(1),1)*10;
            t2=round(rectangle.Position(1)+rectangle.Position(3),1)*10;
            v1=rectangle.Position(2)*ones(1,t2-t1+1);
            v2=(rectangle.Position(2)+rectangle.Position(4))*ones(1,t2-t1+1);
            
            for i = 1:m
                if data.idx(i) ~= 0
                    continue;
                end
                
                if sum(data.waveforms(i,t1:t2) >= v1 & data.waveforms(i,t1:t2) <= v2) >= 1
                    delete(c(i));
                    data.idx(i) = n;
                end
            end
            rest = find(~data.idx);          
            spikes_max = max(data.waveforms(rest,:),[],'all');
            spikes_min = min(data.waveforms(rest,:),[],'all');
            try
                axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);   
            catch
            end
            delete(rectangle);
            k = length(rest);
            title(app.UIAxes,{'waveforms number:' k}); 
           
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClusterxListBox.Items = {};
            addpath(genpath('Functions'))
            addpath(genpath('MyFunctions'))
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global n;
            global flag;
            flag = 0;
            n = 0;
            global X;
            global data;
            
            app.ClusterxListBox.Items = {};
            
            if isempty(app.DataPathEditField.Value)
                fig = app.UIFigure;
                message = sprintf('path not found');
                uialert(fig,message,'Warning',...
                'Icon','warning');
            else
                d = uiprogressdlg(app.UIFigure,'Title','data Loading . . .',...
                'Indeterminate','on');
                
                X = spikeSorting(app.DataPathEditField.Value,d);
                
                n = max(data.idx);
                for i = 1:n
                    app.ClusterxListBox.Items{end+1} = ['Cluster ' int2str(i)];
                end
                
                waveformsPlotting(app,'Show All Clusters');
                close(d);
            end
     
        end

        % Button pushed function: SelectClusterButton
        function SelectClusterButtonPushed(app, event)
            global rectangle;
            global flag;
            
            if  flag == 1
                waveformsPlotting(app,'showUnsortedSpikes');
                flag = 0;
            end 
            rectangle = drawrectangle(app.UIAxes,'Color','r');
        end

        % Button pushed function: AddnewClusterButton
        function AddnewClusterButtonPushed(app, event)
            global n;
            global rectangle;
            if isvalid(rectangle)
               n = n + 1;
               addClusters(app);
               app.ClusterxListBox.Items{end+1} = ['Cluster ' int2str(n)];
            end
        end

        % Button pushed function: RunFunctionButton
        function RunFunctionButtonPushed(app, event)
            value = app.FunctionListBox.Value;
      
            global flag;
            global X;
            global data;
            
            flag = 1;              
            switch value
                case 'Show all Spikes'
                    waveformsPlotting(app,'Show all Spikes');
                case 'Show x'
                    waveformsPlotting(app,"Show x");
                case 'Show All Clusters'
                    waveformsPlotting(app,"Show All Clusters");
                case 'View x in data'

                case 'View all in data'
                    
                case 'x''s frequency'
                    n = size(app.ClusterxListBox.Value,2);
                    Clusters2draw = zeros(n,1);
                    for i = 1:n
                        name = split(app.ClusterxListBox.Value(i),' ');
                        Clusters2draw(i) = str2double(name{2});
                    end
                    frequency_visualization(app.DataPathEditField.Value,data,size(X,1),Clusters2draw);
                case 'all''s frequency'
                    frequency_visualization(app.DataPathEditField.Value,data,size(X,1),0);
            end         
        end

        % Button pushed function: DeleteClusterxButton
        function DeleteClusterxButtonPushed(app, event)
            global data;
            global flag;
            global c;
            global n;
            
            name = split(app.ClusterxListBox.Value,' ');
            m = str2double(name{2});
            
            index = data.idx == m;
            
            data.idx(index) = 0;                
            data.idx(data.idx>m) = data.idx(data.idx>m)-1;  
            app.ClusterxListBox.Items(:,end) = [];
            n = n-1;
            
            if flag == 0
                hold(app.UIAxes);
                spikes_length = size(data.waveforms,2);
                t = (0.1:0.1:spikes_length/10)';
                
                c(index) = plot(app.UIAxes,t,data.waveforms(index,:)','Color','#0072BD');
                
                rest = data.idx == 0;
                spikes_max = max(data.waveforms(rest,:),[],'all');
                spikes_min = min(data.waveforms(rest,:),[],'all');  
                k=length(rest);
                title(app.UIAxes,{'waveforms number:' k});
                axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]); 
            else
                waveformsPlotting(app,'showUnsortedSpikes');
                flag = 0;
            end
        end

        % Button pushed function: AddtoClusterxButton
        function AddtoClusterxButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {446, 446};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataPathEditFieldLabel = uilabel(app.LeftPanel);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.LeftPanel, 'text');
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectClusterButton = uibutton(app.LeftPanel, 'push');
            app.SelectClusterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectClusterButtonPushed, true);
            app.SelectClusterButton.Text = 'Select Cluster';
            app.AddnewClusterButton = uibutton(app.LeftPanel, 'push');
            app.AddnewClusterButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewClusterButtonPushed, true);
            app.AddnewClusterButton.Text = 'Add new Cluster';
            app.FunctionListBoxLabel = uilabel(app.LeftPanel);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.LeftPanel);
            app.FunctionListBox.Items = {'Show all Spikes', 'Show x', 'Show All Clusters', 'View x in data', 'View all in data', 'x''s frequency', 'all''s frequency'};
            app.FunctionListBox.Value = 'Show all Spikes';
            app.RunFunctionButton = uibutton(app.LeftPanel, 'push');
            app.RunFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @RunFunctionButtonPushed, true);
            app.RunFunctionButton.Text = 'Run Function';
            app.DeleteClusterxButton = uibutton(app.LeftPanel, 'push');
            app.DeleteClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteClusterxButtonPushed, true);
            app.DeleteClusterxButton.Text = 'Delete Cluster x';
            app.ClusterxListBoxLabel = uilabel(app.LeftPanel);
            app.ClusterxListBoxLabel.Text = 'Cluster x';
            app.ClusterxListBox = uilistbox(app.LeftPanel);
            app.ClusterxListBox.Items = {'Unsorted'};
            app.ClusterxListBox.Multiselect = 'on';
            app.ClusterxListBox.Value = {};
            app.AddtoClusterxButton = uibutton(app.LeftPanel, 'push');
            app.AddtoClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoClusterxButtonPushed, true);
            app.AddtoClusterxButton.Text = 'Add to Cluster x';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'Time(ms)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spike_sorting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end